classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ShowRadialLinesCheckBox        matlab.ui.control.CheckBox
        SpecifySpideryMeshButtonGroup  matlab.ui.container.ButtonGroup
        OnOffCheckBox                  matlab.ui.control.CheckBox
        RadialLinesButton              matlab.ui.control.RadioButton
        VanishPointButton              matlab.ui.control.RadioButton
        RearWindowButton               matlab.ui.control.RadioButton
        CurrentPositionEditField       matlab.ui.control.EditField
        GridLayout2                    matlab.ui.container.GridLayout
        OpenFileButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags

        spideryGrid_toggle   % specifies wether the grid should be displayed or not
        InputImage      % documentation of the Image

        % spider mesh
        OuterRect       % specify the four corners of outer rectangle
        % upleft-upright-downright-downleft
        InnerRect       %stores the values of the inner rectangle
        VanishPoint     %stores the value of the vanish_point --> idle is  the middle of the picture
    end

    properties (Access = public)
        %draw_outer_rect     % control the drawing of outer rect
        %draw_inner_rect     % control the drawing of inner rect
            
    end

    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)

        % check if mouse is within axes
        function [new_point, isInAxes] = check_mouse_within_axes(app)
            isInAxes = true;
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if cp(1) < 0 % x-axis
                cp(1) = 0;
                isInAxes = false;
            elseif cp(1) > size(app.InputImage,2)
                cp(1) = size(app.InputImage,2);
                isInAxes = false;
            end
            if cp(2) < 0 % y-axis
                cp(2) = 0;
                isInAxes = false;
            elseif cp(2) > size(app.InputImage,1)
                cp(2) = size(app.InputImage,1);
                isInAxes = false;
            end
            new_point = cp;
        end

        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint(app)
            % check wether the VP is drawed
            if app.drawVP_flag
                return
            end
            % check weheter the mouse is within outerrect and plot the selection of VanishPoint
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return
            end
            app.draw_vp = drawpoint(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VP', 0, 'white'};
            set(app.draw_vp, NameArray, ValueArray);
            app.drawVP_flag = true;
        end

        % draw the inner rectangle
        function specify_RearWindow(app)
            if app.drawRW_flag
                return
            end

            %[~, isInAxes] = app.check_mouse_within_axes();
            %if ~isInAxes
            %    return
            %end
            app.draw_inner_rect = drawrectangle(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'IR', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);
            app.drawRW_flag = true;
        end

        function specify_RadialLines(app, vp)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return;
            end
            % plot the radial lines
            app.draw_radial = [];
            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [vp app.OuterFixies(:,l)]';
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end
            for m = 1:length(app.InnerRect)
                correspond_point = app.correspond_point_to_outer_rect(vp, app.InnerRect(:,m));
                radial_line = [vp correspond_point]';
                app.draw_radial(l+m) = drawline(app.UIAxes, 'Position', radial_line, 'InteractionsAllowed', 'none', 'Linewidth', 2);
            end
        end

        function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            app.draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OR', 0, 'white', 0, 'none',};
            set(app.draw_outer_rect, NameArray, ValueArray);

            % preparation of radiallines based on outer_fixies
            app.OuterFixies = zeros(2,app.radial_size*length(app.OuterRect));
            n = 1;
            for i = 1:app.radial_size
                for j = 1:length(app.OuterRect)
                    app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
                    app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
                    n = n + 2;
                end
            end
            app.OuterFixies = unique(app.OuterFixies', 'rows')';
        end

        % project the radiallines of innerrect on the outerrect
        function CorrespondPoint = correspond_point_to_outer_rect(app, point, compared_point)
            % determine the zone of the point in comparison to the compared
            % point
            if point(1) == compared_point(1)
                if point(2) > compared_point(2)
                    CorrespondPoint = [point(1); 0];
                elseif point(2) < compared_point(2)
                    CorrespondPoint = [point(1); size(app.InputImage,2)];
                else
                    CorrespondPoint = point;
                end
                return;
            else
                k = (point(2)-compared_point(2))/(point(1)-compared_point(1));
                b = point(2)-k*point(1);
                if point(1) > compared_point(1)
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-0));
                    theta_2 = atan((compared_point(2)-0)/(compared_point(1)-0));
                    if phi < theta_1
                        % edge unten
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    elseif phi >= theta_1 && phi < theta_2
                        % edge links
                        CorrespondPoint = [0; b];
                    else
                        % edge oben
                        CorrespondPoint = [-b/k; 0];
                    end
                else
                    phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                    theta_1 = atan((compared_point(2)-0)/(compared_point(1)-size(app.InputImage,2)));
                    theta_2 = atan((compared_point(2)-size(app.InputImage,1))/(compared_point(1)-size(app.InputImage,2)));
                    if phi < theta_1
                        % edge oben
                        CorrespondPoint = [-b/k; 0];
                    elseif phi >= theta_1 && phi < theta_2
                        % edge rechts
                        CorrespondPoint = [size(app.InputImage,2); k*size(app.InputImage,2)+b];
                    else
                        % edge unten
                        CorrespondPoint = [(size(app.InputImage,1)-b)/k; size(app.InputImage,1)];
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'on');
            title(app.UIAxes,'Tour Into The Picture');

            app.radial_size = 3;

        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            %startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                switch app.select_process
                    case 1
                        app.specify_VanishPoint();
                        set(app.draw_vp,'InteractionsAllowed','all');
                        set(app.draw_inner_rect,'InteractionsAllowed','none')
                    case 2
                        app.specify_RearWindow();
                        set(app.draw_vp,'InteractionsAllowed','none');
                        set(app.draw_inner_rect,'InteractionsAllowed','all')
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            set(app.CurrentPositionEditField,'Value',sprintf('%.2f, %.2f',app.UIAxes.CurrentPoint(1,1:2)))
            
%             if app.drawRW_flag
%                 inner_rect_pos = app.draw_inner_rect.Position;
%                 app.InnerRect = [[inner_rect_pos(1);inner_rect_pos(2)],...
%                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)],...
%                     [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)+inner_rect_pos(4)],...
%                     [inner_rect_pos(1);inner_rect_pos(2)+inner_rect_pos(4)]];
%             end
%             if app.ShowRadialLinesCheckBox.Value
%                 delete(app.draw_radial)
%                 app.specify_RadialLines(app.VanishPoint);
%             end
        end

        % Selection changed function: SpecifySpideryMeshButtonGroup
        function SpecifySpideryMeshButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpecifySpideryMeshButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'VanishPoint'
                    app.select_process = 1;
                case 'RearWindow'
                    app.select_process = 2;
                case 'RadialLines'
                    app.select_process = 3;
            end
        end

        % Value changed function: ShowRadialLinesCheckBox
        function ShowRadialLinesCheckBoxValueChanged(app, event)
            value = app.ShowRadialLinesCheckBox.Value;
            if value
                app.specify_RadialLines();
            else
                delete(app.draw_radial);
            end
        end

        % Value changed function: OnOffCheckBox
        function OnOffCheckBoxValueChanged(app, event)
            value = app.OnOffCheckBox.Value;
            app.spideryGrid_toggle=value;

            if value==false
                %% close the grid plot so that it is not shown anymore

            else
                %%plot the grid with the previous parameters

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OpenFileButton = uibutton(app.GridLayout2, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Icon = 'FileIcon.png';
            app.OpenFileButton.Text = 'Open File';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrentPositionEditField = uieditfield(app.GridLayout3, 'text');
            app.SpecifySpideryMeshButtonGroup = uibuttongroup(app.GridLayout3);
            app.SpecifySpideryMeshButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpecifySpideryMeshButtonGroupSelectionChanged, true);
            app.SpecifySpideryMeshButtonGroup.Title = 'Specify Spidery Mesh';
            app.RearWindowButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RearWindowButton.Tag = 'R';
            app.RearWindowButton.Text = 'RearWindow';
            app.VanishPointButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.VanishPointButton.Tag = 'V';
            app.VanishPointButton.Text = 'VanishPoint';
            app.VanishPointButton.Value = true;
            app.RadialLinesButton = uiradiobutton(app.SpecifySpideryMeshButtonGroup);
            app.RadialLinesButton.Tag = 'R';
            app.RadialLinesButton.Text = 'RadialLines';
            app.OnOffCheckBox = uicheckbox(app.SpecifySpideryMeshButtonGroup);
            app.OnOffCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnOffCheckBoxValueChanged, true);
            app.OnOffCheckBox.Text = 'On/Off';
            app.ShowRadialLinesCheckBox = uicheckbox(app.GridLayout3);
            app.ShowRadialLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRadialLinesCheckBoxValueChanged, true);
            app.ShowRadialLinesCheckBox.Text = 'ShowRadialLines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end